classdef PolarPlotAppExample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NoofSidesEditFieldLabel  matlab.ui.control.Label
        NoofSidesEditField       matlab.ui.control.NumericEditField
        DropDown                 matlab.ui.control.DropDown
        Button                   matlab.ui.control.Button
        BaseLabel                matlab.ui.control.Label
        ExponentLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pax % Polar axes
    end
    
    methods (Access = private)
        
        function updateplot(app)
            
            % Define theta. Get a and b.
            theta = 0:pi/1800:2*pi;
            a = app.NoofSidesEditField.Value;
            b = app.bEditField.Value;
            
            % Calculate r and plot it
            r = (1 + sin(a.*theta)).^(1/b);
            plotline = polarplot(app.Pax, theta, r);
            
            % Set line color
            plotline.Color = app.DropDown.Value;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Replace 0 in equation with unicode theta
            app.BaseLabel.Text = ['r = ( 1 + sin(a' char(00952) ') )'];
            
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [260 55 230 230];
            
            % Plot the polar function
            updateplot(app);
        end

        % Value changed function: DropDown, NoofSidesEditField
        function ComponentValueChanged(app, event)
            % Plot the polar function
            updateplot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Polar Plot';
            app.NoofSidesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofSidesEditFieldLabel.Text = 'No. of Sides';
            app.NoofSidesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofSidesEditField.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.NoofSidesEditField.Value = 5;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Blue Line', 'Red Line', 'Green Line', 'Black Line'};
            app.DropDown.ItemsData = {'b', 'r', 'g', 'k'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentValueChanged, true);
            app.DropDown.Value = 'b';
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'r = ( 1 + sin(a0) )';
            app.ExponentLabel = uilabel(app.UIFigure);
            app.ExponentLabel.Text = '1/b';
            app.Button = uibutton(app.UIFigure, 'push');
        end
    end

    methods (Access = public)

        % Construct app
        function app = PolarPlotAppExample

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end